classdef GroupProject < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        MenuTab                        matlab.ui.container.Tab
        CheckOutPanel                  matlab.ui.container.Panel
        TextArea2                      matlab.ui.control.TextArea
        ProceedButton                  matlab.ui.control.Button
        ChangePhpEditField             matlab.ui.control.EditField
        ChangePhpLabel                 matlab.ui.control.Label
        ToPayPhpEditField              matlab.ui.control.EditField
        ToPayPhpLabel                  matlab.ui.control.Label
        TenderedAmountEditField        matlab.ui.control.EditField
        TenderedAmountEditFieldLabel   matlab.ui.control.Label
        XButton                        matlab.ui.control.Button
        TextArea                       matlab.ui.control.TextArea
        TextAreaLabel                  matlab.ui.control.Label
        CheckOutButton                 matlab.ui.control.Button
        VoidButton                     matlab.ui.control.Button
        SeaSaltCreamButton             matlab.ui.control.Button
        IcedTeaButton                  matlab.ui.control.Button
        OrangeJuiceButton              matlab.ui.control.Button
        IcedChocolateButton            matlab.ui.control.Button
        IcedMatchaButton               matlab.ui.control.Button
        IcedCoffeeButton               matlab.ui.control.Button
        StrawberryMilkLatteButton      matlab.ui.control.Button
        TteokbokkiwithEggCheeseButton  matlab.ui.control.Button
        BuffaloSkinnyFriesButton       matlab.ui.control.Button
        CrispyLoopsFriesButton         matlab.ui.control.Button
        CrissCutFriesButton            matlab.ui.control.Button
        ChickenEggdropSandwichButton   matlab.ui.control.Button
        HungarianSausageEggDropButton_2  matlab.ui.control.Button
        PremiumBeefBulgogiSandwichButton_4  matlab.ui.control.Button
        SpamandEggSandwichButton       matlab.ui.control.Button
        BaconEggandCheeseButton_3      matlab.ui.control.Button
        DoubleHamEggCheeseButton       matlab.ui.control.Button
        TunaMeltSandwichButton         matlab.ui.control.Button
        EggSaladSandwichButton         matlab.ui.control.Button
        EggCheddarSandwichButton       matlab.ui.control.Button
        AnalyticsTab                   matlab.ui.container.Tab
        UITable                        matlab.ui.control.Table
        GraphDropDown                  matlab.ui.control.DropDown
        GraphDropDownLabel             matlab.ui.control.Label
        DatePicker                     matlab.ui.control.DatePicker
        DatePickerLabel                matlab.ui.control.Label
        UIAxes2                        matlab.ui.control.UIAxes
        UIAxes                         matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        proceed                    double = 0
        Total                      double = 0
        EggCheddarSandwichCounter  double = 0
        EggSaladSandwichCounter    double = 0
        TunaMeltSandwichCounter    double = 0
        DoubleHamEggCheeseCounter  double = 0
        BaconEggandCheeseCounter   double = 0
        SpamandEggSandwichCounter  double = 0
        PremiumBeefBulgogiSandwichCounter  double = 0
        HungarianSausageEggDropCounter double = 0
        ChickenEggdropSandwichCounter double = 0
        CrissCutFriesCounter double = 0
        CrispyLoopsFriesCounter double = 0
        BuffaloSkinnyFriesCounter double = 0
        TteokbokkiwithEggCheeseCounter double = 0
        StrawberryMilkLatteCounter double = 0
        IcedCoffeeCounter double = 0
        IcedMatchaCounter double = 0
        IcedChocolateCounter double = 0
        OrangeJuiceCounter double = 0
        IcedTeaCounter double = 0
        SeaSaltCreamCounter double = 0
        EggCheddarSandwichString   char   = ''
        EggSaladSandwichString     char   = ''
        TunaMeltSandwichString     char   = ''
        DoubleHamEggCheeseString   char   = ''
        BaconEggandCheeseString    char   = ''
        SpamandEggSandwichString   char   = ''
        PremiumBeefBulgogiSandwichString char   = ''
        HungarianSausageEggDropString char = ''
        ChickenEggdropSandwichString char = ''
        CrissCutFriesString char = ''
        CrispyLoopsFriesString char = ''
        BuffaloSkinnyFriesString char = ''
        TteokbokkiwithEggCheeseString char = ''
        StrawberryMilkLatteString char = ''
        IcedCoffeeString char = ''
        IcedMatchaString char = ''
        IcedChocolateString char = ''
        OrangeJuiceString char = ''
        IcedTeaString char = ''
        SeaSaltCreamString char = ''
        PreviousText char = '';
    end
    
    
    methods (Access = private)
        
        function updateTextArea(app)
            str = [app.SeaSaltCreamString,app.OrangeJuiceString,app.IcedTeaString,app.IcedChocolateString,app.IcedMatchaString,app.IcedCoffeeString,app.StrawberryMilkLatteString,app.TteokbokkiwithEggCheeseString,app.BuffaloSkinnyFriesString,app.CrispyLoopsFriesString,app.CrissCutFriesString,app.ChickenEggdropSandwichString,app.HungarianSausageEggDropString,app.PremiumBeefBulgogiSandwichString,app.SpamandEggSandwichString,app.BaconEggandCheeseString,app.DoubleHamEggCheeseString,app.TunaMeltSandwichString,app.EggCheddarSandwichString, app.EggSaladSandwichString, sprintf('Total: %.2f', app.Total)];
            app.PreviousText = app.TextArea.Value;
            app.TextArea.Value = str;
        end
    end
       

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: EggCheddarSandwichButton
        function EggCheddarSandwichButtonPushed(app, event)

            % Increment the counter
            app.EggCheddarSandwichCounter = app.EggCheddarSandwichCounter + 1;
        
            % Set the price and cost for the Egg Cheddar Sandwich button
            app.EggCheddarSandwichButton.UserData = struct('Price', 99, 'Cost', 53.53);
        
            % Get the price and cost for the Egg Cheddar Sandwich button
            price = app.EggCheddarSandwichButton.UserData.Price;
            cost = app.EggCheddarSandwichButton.UserData.Cost;
        
            % Add the price to the running total
            app.Total = app.Total + price;
        
            % Format the name, counter, price, and total as a string
            name = 'Egg Cheddar Sandwich';
            counter = app.EggCheddarSandwichCounter;
            str = sprintf('#%d %s       \t\t%.2f\n',counter, name, price);
        
            % Update the text area with the formatted string
            app.EggCheddarSandwichString = [app.EggCheddarSandwichString, str];
            app.updateTextArea();
        end

        % Button pushed function: EggSaladSandwichButton
        function EggSaladSandwichButtonPushed(app, event)
            app.EggSaladSandwichCounter = app.EggSaladSandwichCounter + 1;

            % Set the price and cost for the Egg Salad Sandwich button
            app.EggSaladSandwichButton.UserData = struct('Price', 129, 'Cost', 69.75);
        
            % Get the price and cost for the Egg Salad Sandwich button
            price = app.EggSaladSandwichButton.UserData.Price;
            cost = app.EggSaladSandwichButton.UserData.Cost;
        
            % Add the price to the running total
            app.Total = app.Total + price;
        
            % Format the name, counter, price, and total as a string
            name = 'Egg Salad Sandwich';
            counter = app.EggSaladSandwichCounter;
            str = sprintf('#%d %s      \t\t\t%.2f\n',counter, name, price);
        
            % Update the text area with the formatted string
            app.EggSaladSandwichString = [app.EggSaladSandwichString, str];
            app.updateTextArea();
        end

        % Button pushed function: TunaMeltSandwichButton
        function TunaMeltSandwichButtonPushed(app, event)
            app.TunaMeltSandwichCounter = app.TunaMeltSandwichCounter + 1;

            % Set the price and cost
            app.TunaMeltSandwichButton.UserData = struct('Price', 159, 'Cost', 80.39);
        
            % Get the price and cost
            price = app.TunaMeltSandwichButton.UserData.Price;
            cost = app.TunaMeltSandwichButton.UserData.Cost;
        
            % Add the price to the running total
            app.Total = app.Total + price;
        
            % Format the name, counter, price, and total as a string
            name = 'Tuna Melt Sandwich';
            counter = app.TunaMeltSandwichCounter;
            str = sprintf('#%d %s       \t\t\t%.2f\n',counter, name, price);
        
            % Update the text area with the formatted string
            app.TunaMeltSandwichString = [app.TunaMeltSandwichString, str];
            app.updateTextArea();
        end

        % Button pushed function: DoubleHamEggCheeseButton
        function DoubleHamEggCheeseButtonPushed(app, event)
            app.DoubleHamEggCheeseCounter = app.DoubleHamEggCheeseCounter + 1;

            % Set the price and cost
            app.DoubleHamEggCheeseButton.UserData = struct('Price', 149, 'Cost', 69.13);
        
            % Get the price and cost
            price = app.DoubleHamEggCheeseButton.UserData.Price;
            cost = app.DoubleHamEggCheeseButton.UserData.Cost;
        
            % Add the price to the running total
            app.Total = app.Total + price;
        
            % Format the name, counter, price, and total as a string
            name = 'Double Ham Egg & Cheese';
            counter = app.DoubleHamEggCheeseCounter;
            str = sprintf('#%d %s       \t%.2f\n',counter, name, price);
        
            % Update the text area with the formatted string
            app.DoubleHamEggCheeseString = [app.DoubleHamEggCheeseString, str];
            app.updateTextArea();
        end

        % Button pushed function: BaconEggandCheeseButton_3
        function BaconEggandCheeseButton_3Pushed(app, event)
            app.BaconEggandCheeseCounter = app.BaconEggandCheeseCounter + 1;

            % Set the price and cost
            app.BaconEggandCheeseButton_3.UserData = struct('Price', 159, 'Cost', 66.21);
        
            % Get the price and cost
            price = app.BaconEggandCheeseButton_3.UserData.Price;
            cost = app.BaconEggandCheeseButton_3.UserData.Cost;
        
            % Add the price to the running total
            app.Total = app.Total + price;
        
            % Format the name, counter, price, and total as a string
            name = 'Bacon Egg & Cheese Sandwich';
            counter = app.BaconEggandCheeseCounter;
            str = sprintf('#%d %s        %.2f\n',counter, name, price);
        
            % Update the text area with the formatted string
            app.BaconEggandCheeseString = [app.BaconEggandCheeseString, str];
            app.updateTextArea();
        end

        % Button pushed function: SpamandEggSandwichButton
        function SpamandEggSandwichButtonPushed(app, event)
            app.SpamandEggSandwichCounter = app.SpamandEggSandwichCounter + 1;

            % Set the price and cost
            app.SpamandEggSandwichButton.UserData = struct('Price', 159, 'Cost', 81.75);
        
            % Get the price and cost
            price = app.SpamandEggSandwichButton.UserData.Price;
            cost = app.SpamandEggSandwichButton.UserData.Cost;
        
            % Add the price to the running total
            app.Total = app.Total + price;
        
            % Format the name, counter, price, and total as a string
            name = 'Spam and Egg Sandwich';
            counter = app.SpamandEggSandwichCounter;
            str = sprintf('#%d %s        \t\t%.2f\n',counter, name, price);
        
            % Update the text area with the formatted string
            app.SpamandEggSandwichString = [app.SpamandEggSandwichString, str];
            app.updateTextArea();
        end

        % Button pushed function: PremiumBeefBulgogiSandwichButton_4
        function PremiumBeefBulgogiSandwichButton_4Pushed(app, event)
            app.PremiumBeefBulgogiSandwichCounter = app.PremiumBeefBulgogiSandwichCounter + 1;

            % Set the price and cost
            app.PremiumBeefBulgogiSandwichButton_4.UserData = struct('Price', 169, 'Cost', 88.05);
        
            % Get the price and cost
            price = app.PremiumBeefBulgogiSandwichButton_4.UserData.Price;
            cost = app.PremiumBeefBulgogiSandwichButton_4.UserData.Cost;
        
            % Add the price to the running total
            app.Total = app.Total + price;
        
            % Format the name, counter, price, and total as a string
            name = 'Premium Beef Bulgogi Sandwich';
            counter = app.PremiumBeefBulgogiSandwichCounter;
            str = sprintf('#%d %s       %.2f\n',counter, name, price);
        
            % Update the text area with the formatted string
            app.PremiumBeefBulgogiSandwichString = [app.PremiumBeefBulgogiSandwichString, str];
            app.updateTextArea();
        end

        % Button pushed function: HungarianSausageEggDropButton_2
        function HungarianSausageEggDropButton_2Pushed(app, event)
            app.HungarianSausageEggDropCounter = app.HungarianSausageEggDropCounter + 1;

            % Set the price and cost
            app.HungarianSausageEggDropButton_2.UserData = struct('Price', 169, 'Cost', 87.63);
        
            % Get the price and cost
            price = app.HungarianSausageEggDropButton_2.UserData.Price;
            cost = app.HungarianSausageEggDropButton_2.UserData.Cost;
        
            % Add the price to the running total
            app.Total = app.Total + price;
        
            % Format the name, counter, price, and total as a string
            name = 'Hungarian Sausage Egg Drop';
            counter = app.HungarianSausageEggDropCounter;
            str = sprintf('#%d %s       \t%.2f\n',counter, name, price);
        
            % Update the text area with the formatted string
            app.HungarianSausageEggDropString = [app.HungarianSausageEggDropString, str];
            app.updateTextArea();
        end

        % Button pushed function: ChickenEggdropSandwichButton
        function ChickenEggdropSandwichButtonPushed(app, event)
            app.ChickenEggdropSandwichCounter = app.ChickenEggdropSandwichCounter + 1;

            % Set the price and cost
            app.ChickenEggdropSandwichButton.UserData = struct('Price', 169, 'Cost', 83.08);
        
            % Get the price and cost
            price = app.ChickenEggdropSandwichButton.UserData.Price;
            cost = app.ChickenEggdropSandwichButton.UserData.Cost;
        
            % Add the price to the running total
            app.Total = app.Total + price;
        
            % Format the name, counter, price, and total as a string
            name = 'Chicken Eggdrop Sandwich';
            counter = app.ChickenEggdropSandwichCounter;
            str = sprintf('#%d %s       \t%.2f\n',counter, name, price);
        
            % Update the text area with the formatted string
            app.ChickenEggdropSandwichString = [app.ChickenEggdropSandwichString, str];
            app.updateTextArea();
        end

        % Button pushed function: CrissCutFriesButton
        function CrissCutFriesButtonPushed(app, event)
            app.CrissCutFriesCounter = app.CrissCutFriesCounter + 1;

            % Set the price and cost
            app.CrissCutFriesButton.UserData = struct('Price', 109, 'Cost', 62.80);
        
            % Get the price and cost
            price = app.CrissCutFriesButton.UserData.Price;
            cost = app.CrissCutFriesButton.UserData.Cost;
        
            % Add the price to the running total
            app.Total = app.Total + price;
        
            % Format the name, counter, price, and total as a string
            name = 'Criss Cut Fries';
            counter = app.CrissCutFriesCounter;
            str = sprintf('#%d %s       \t\t\t\t%.2f\n',counter, name, price);
        
            % Update the text area with the formatted string
            app.CrissCutFriesString = [app.CrissCutFriesString, str];
            app.updateTextArea();
        end

        % Button pushed function: CrispyLoopsFriesButton
        function CrispyLoopsFriesButtonPushed(app, event)
            app.CrispyLoopsFriesCounter = app.CrispyLoopsFriesCounter + 1;

            % Set the price and cost
            app.CrispyLoopsFriesButton.UserData = struct('Price', 109, 'Cost', 62.80);
        
            % Get the price and cost
            price = app.CrispyLoopsFriesButton.UserData.Price;
            cost = app.CrispyLoopsFriesButton.UserData.Cost;
        
            % Add the price to the running total
            app.Total = app.Total + price;
        
            % Format the name, counter, price, and total as a string
            name = 'Crispy Loops Fries';
            counter = app.CrispyLoopsFriesCounter;
            str = sprintf('#%d %s       \t\t\t%.2f\n',counter, name, price);
        
            % Update the text area with the formatted string
            app.CrispyLoopsFriesString = [app.CrispyLoopsFriesString, str];
            app.updateTextArea();
        end

        % Button pushed function: BuffaloSkinnyFriesButton
        function BuffaloSkinnyFriesButtonPushed(app, event)
             app.BuffaloSkinnyFriesCounter = app.BuffaloSkinnyFriesCounter + 1;

            % Set the price and cost
            app.BuffaloSkinnyFriesButton.UserData = struct('Price', 109, 'Cost', 62.80);
        
            % Get the price and cost
            price = app.BuffaloSkinnyFriesButton.UserData.Price;
            cost = app.BuffaloSkinnyFriesButton.UserData.Cost;
        
            % Add the price to the running total
            app.Total = app.Total + price;
        
            % Format the name, counter, price, and total as a string
            name = 'Buffalo Skinny Fries';
            counter = app.BuffaloSkinnyFriesCounter;
            str = sprintf('#%d %s       \t\t\t%.2f\n',counter, name, price);
        
            % Update the text area with the formatted string
            app.BuffaloSkinnyFriesString = [app.BuffaloSkinnyFriesString, str];
            app.updateTextArea();
        end

        % Button pushed function: TteokbokkiwithEggCheeseButton
        function TteokbokkiwithEggCheeseButtonPushed(app, event)
            app.TteokbokkiwithEggCheeseCounter = app.TteokbokkiwithEggCheeseCounter + 1;

            % Set the price and cost
            app.TteokbokkiwithEggCheeseButton.UserData = struct('Price', 120, 'Cost', 53.66);
        
            % Get the price and cost
            price = app.TteokbokkiwithEggCheeseButton.UserData.Price;
            cost = app.TteokbokkiwithEggCheeseButton.UserData.Cost;
        
            % Add the price to the running total
            app.Total = app.Total + price;
        
            % Format the name, counter, price, and total as a string
            name = 'Tteokbokki with Egg & Cheese';
            counter = app.TteokbokkiwithEggCheeseCounter;
            str = sprintf('#%d %s          %.2f\n',counter, name, price);
        
            % Update the text area with the formatted string
            app.TteokbokkiwithEggCheeseString = [app.TteokbokkiwithEggCheeseString, str];
            app.updateTextArea();
        end

        % Button pushed function: StrawberryMilkLatteButton
        function StrawberryMilkLatteButtonPushed(app, event)
            app.StrawberryMilkLatteCounter = app.StrawberryMilkLatteCounter + 1;

            % Set the price and cost
            app.StrawberryMilkLatteButton.UserData = struct('Price', 90, 'Cost', 40.54);
        
            % Get the price and cost
            price = app.StrawberryMilkLatteButton.UserData.Price;
            cost = app.StrawberryMilkLatteButton.UserData.Cost;
        
            % Add the price to the running total
            app.Total = app.Total + price;
        
            % Format the name, counter, price, and total as a string
            name = 'Strawberry Milk Latte';
            counter = app.StrawberryMilkLatteCounter;
            str = sprintf('#%d %s       \t\t\t%.2f\n',counter, name, price);
        
            % Update the text area with the formatted string
            app.StrawberryMilkLatteString = [app.StrawberryMilkLatteString, str];
            app.updateTextArea();
        end

        % Button pushed function: IcedCoffeeButton
        function IcedCoffeeButtonPushed(app, event)
            app.IcedCoffeeCounter = app.IcedCoffeeCounter + 1;

            % Set the price and cost
            app.IcedCoffeeButton.UserData = struct('Price', 70, 'Cost', 26.25);
        
            % Get the price and cost
            price = app.IcedCoffeeButton.UserData.Price;
            cost = app.IcedCoffeeButton.UserData.Cost;
        
            % Add the price to the running total
            app.Total = app.Total + price;
        
            % Format the name, counter, price, and total as a string
            name = 'Iced Coffee';
            counter = app.IcedCoffeeCounter;
            str = sprintf('#%d %s       \t\t\t\t\t%.2f\n',counter, name, price);
        
            % Update the text area with the formatted string
            app.IcedCoffeeString = [app.IcedCoffeeString, str];
            app.updateTextArea();
        end

        % Button pushed function: VoidButton
        function VoidButtonPushed(app, event)
            % Reset all counter variables to 0
            app.Total = 0;
            app.EggCheddarSandwichCounter = 0;
            app.EggSaladSandwichCounter = 0;
            app.TunaMeltSandwichCounter = 0;
            app.DoubleHamEggCheeseCounter = 0;
            app.BaconEggandCheeseCounter = 0;
            app.SpamandEggSandwichCounter = 0;
            app.PremiumBeefBulgogiSandwichCounter = 0;
            app.HungarianSausageEggDropCounter = 0;
            app.ChickenEggdropSandwichCounter = 0;
            app.CrissCutFriesCounter = 0;
            app.CrispyLoopsFriesCounter = 0;
            app.BuffaloSkinnyFriesCounter = 0;
            app.TteokbokkiwithEggCheeseCounter = 0;
            app.StrawberryMilkLatteCounter = 0;
            app.IcedCoffeeCounter = 0;
            app.IcedMatchaCounter = 0;
            app.IcedChocolateCounter = 0;
            app.OrangeJuiceCounter = 0;
            app.IcedTeaCounter = 0;
            app.SeaSaltCreamCounter = 0;
            
            % Set all string variables to empty
            app.EggCheddarSandwichString = '';
            app.EggSaladSandwichString = '';
            app.TunaMeltSandwichString = '';
            app.DoubleHamEggCheeseString = '';
            app.BaconEggandCheeseString = '';
            app.SpamandEggSandwichString = '';
            app.PremiumBeefBulgogiSandwichString = '';
            app.HungarianSausageEggDropString = '';
            app.ChickenEggdropSandwichString = '';
            app.CrissCutFriesString = '';
            app.CrispyLoopsFriesString = '';
            app.BuffaloSkinnyFriesString = '';
            app.TteokbokkiwithEggCheeseString = '';
            app.StrawberryMilkLatteString = '';
            app.IcedCoffeeString = '';
            app.IcedMatchaString = '';
            app.IcedChocolateString = '';
            app.OrangeJuiceString = '';
            app.IcedTeaString = '';
            app.SeaSaltCreamString = '';
            
            % Update the text area
            updateTextArea(app);
        end

        % Button pushed function: IcedMatchaButton
        function IcedMatchaButtonPushed(app, event)
            app.IcedMatchaCounter = app.IcedMatchaCounter + 1;

            % Set the price and cost
            app.IcedMatchaButton.UserData = struct('Price', 70, 'Cost', 26.4);
        
            % Get the price and cost
            price = app.IcedMatchaButton.UserData.Price;
            cost = app.IcedMatchaButton.UserData.Cost;
        
            % Add the price to the running total
            app.Total = app.Total + price;
        
            % Format the name, counter, price, and total as a string
            name = 'Iced Matcha';
            counter = app.IcedMatchaCounter;
            str = sprintf('#%d %s       \t\t\t\t%.2f\n',counter, name, price);
        
            % Update the text area with the formatted string
            app.IcedMatchaString = [app.IcedMatchaString, str];
            app.updateTextArea();
        end

        % Button pushed function: IcedChocolateButton
        function IcedChocolateButtonPushed(app, event)
            app.IcedChocolateCounter = app.IcedChocolateCounter + 1;

            % Set the price and cost
            app.IcedChocolateButton.UserData = struct('Price', 70, 'Cost', 21.85);
        
            % Get the price and cost
            price = app.IcedChocolateButton.UserData.Price;
            cost = app.IcedChocolateButton.UserData.Cost;
        
            % Add the price to the running total
            app.Total = app.Total + price;
        
            % Format the name, counter, price, and total as a string
            name = 'Iced Chocolate';
            counter = app.IcedChocolateCounter;
            str = sprintf('#%d %s       \t\t\t\t%.2f\n',counter, name, price);
        
            % Update the text area with the formatted string
            app.IcedChocolateString = [app.IcedChocolateString, str];
            app.updateTextArea();
        end

        % Button pushed function: OrangeJuiceButton
        function OrangeJuiceButtonPushed(app, event)
            app.OrangeJuiceCounter = app.OrangeJuiceCounter + 1;

            % Set the price and cost
            app.OrangeJuiceButton.UserData = struct('Price', 40, 'Cost', 16.66);
        
            % Get the price and cost
            price = app.OrangeJuiceButton.UserData.Price;
            cost = app.OrangeJuiceButton.UserData.Cost;
        
            % Add the price to the running total
            app.Total = app.Total + price;
        
            % Format the name, counter, price, and total as a string
            name = 'Orange Juice';
            counter = app.OrangeJuiceCounter;
            str = sprintf('#%d %s       \t\t\t\t%.2f\n',counter, name, price);
        
            % Update the text area with the formatted string
            app.OrangeJuiceString = [app.OrangeJuiceString, str];
            app.updateTextArea();
        end

        % Button pushed function: IcedTeaButton
        function IcedTeaButtonPushed(app, event)
            app.IcedTeaCounter = app.IcedTeaCounter + 1;

            % Set the price and cost
            app.IcedTeaButton.UserData = struct('Price', 40, 'Cost', 16.66);
        
            % Get the price and cost
            price = app.IcedTeaButton.UserData.Price;
            cost = app.IcedTeaButton.UserData.Cost;
        
            % Add the price to the running total
            app.Total = app.Total + price;
        
            % Format the name, counter, price, and total as a string
            name = 'Iced Tea';
            counter = app.IcedTeaCounter;
            str = sprintf('#%d %s       \t\t\t\t\t%.2f\n',counter, name, price);
        
            % Update the text area with the formatted string
            app.IcedTeaString = [app.IcedTeaString, str];
            app.updateTextArea();
        end

        % Button pushed function: SeaSaltCreamButton
        function SeaSaltCreamButtonPushed(app, event)
            app.SeaSaltCreamCounter = app.SeaSaltCreamCounter + 1;

            % Set the price and cost
            app.SeaSaltCreamButton.UserData = struct('Price', 20, 'Cost', 17.38);
        
            % Get the price and cost
            price = app.SeaSaltCreamButton.UserData.Price;
            cost = app.SeaSaltCreamButton.UserData.Cost;
        
            % Add the price to the running total
            app.Total = app.Total + price;
        
            % Format the name, counter, price, and total as a string
            name = 'SeaSalt Cream';
            counter = app.SeaSaltCreamCounter;
            str = sprintf('#%d %s       \t\t\t\t%.2f\n',counter, name, price);
        
            % Update the text area with the formatted string
            app.SeaSaltCreamString = [app.SeaSaltCreamString, str];
            app.updateTextArea();
        end

        % Button pushed function: CheckOutButton
        function CheckOutButtonPushed(app, event)
            app.CheckOutPanel.Visible = 'on';
            app.ToPayPhpEditField.Value = num2str(app.Total);
        end

        % Button pushed function: XButton
        function XButtonPushed(app, event)
            if app.proceed == 1
                % Reset all counter variables to 0
                app.proceed = 0;
                app.Total = 0;
                app.EggCheddarSandwichCounter = 0;
                app.EggSaladSandwichCounter = 0;
                app.TunaMeltSandwichCounter = 0;
                app.DoubleHamEggCheeseCounter = 0;
                app.BaconEggandCheeseCounter = 0;
                app.SpamandEggSandwichCounter = 0;
                app.PremiumBeefBulgogiSandwichCounter = 0;
                app.HungarianSausageEggDropCounter = 0;
                app.ChickenEggdropSandwichCounter = 0;
                app.CrissCutFriesCounter = 0;
                app.CrispyLoopsFriesCounter = 0;
                app.BuffaloSkinnyFriesCounter = 0;
                app.TteokbokkiwithEggCheeseCounter = 0;
                app.StrawberryMilkLatteCounter = 0;
                app.IcedCoffeeCounter = 0;
                app.IcedMatchaCounter = 0;
                app.IcedChocolateCounter = 0;
                app.OrangeJuiceCounter = 0;
                app.IcedTeaCounter = 0;
                app.SeaSaltCreamCounter = 0;
                
                % Set all string variables to empty
                app.EggCheddarSandwichString = '';
                app.EggSaladSandwichString = '';
                app.TunaMeltSandwichString = '';
                app.DoubleHamEggCheeseString = '';
                app.BaconEggandCheeseString = '';
                app.SpamandEggSandwichString = '';
                app.PremiumBeefBulgogiSandwichString = '';
                app.HungarianSausageEggDropString = '';
                app.ChickenEggdropSandwichString = '';
                app.CrissCutFriesString = '';
                app.CrispyLoopsFriesString = '';
                app.BuffaloSkinnyFriesString = '';
                app.TteokbokkiwithEggCheeseString = '';
                app.StrawberryMilkLatteString = '';
                app.IcedCoffeeString = '';
                app.IcedMatchaString = '';
                app.IcedChocolateString = '';
                app.OrangeJuiceString = '';
                app.IcedTeaString = '';
                app.SeaSaltCreamString = '';
                app.TextArea2.Visible = 'off';
                app.TenderedAmountEditField.Value = '';
                app.ChangePhpEditField.Value = '';

                % Update the text area
                updateTextArea(app);
                app.CheckOutPanel.Visible = 'off';
            else
                app.CheckOutPanel.Visible = 'off';
            end
        
            
        end

        % Button pushed function: ProceedButton
        function ProceedButtonPushed(app, event)
            tenderedAmount = str2double(app.TenderedAmountEditField.Value);
            change = tenderedAmount - app.Total;
            if tenderedAmount < app.Total
                app.TextArea2.Value = 'Insufficient Amount!';
                app.TextArea2.Visible = 'on';
            end
            if tenderedAmount >= app.Total
                app.TextArea2.Value = 'Thank you for your purchase!';
                app.TextArea2.Visible = 'on';
                app.ChangePhpEditField.Value = num2str(change);
                app.proceed = 1;


                % Export data to CSV file
                date = repmat(datetime('today'),20,1);
                date2 = repmat(datetime('now'),20,1);
                time = timeofday(date2);
                item_name = {'Egg Cheddar Sandwich', 'Egg Salad','Tuna Melt','Double Ham','Bacon Egg and Cheese','Spam and Egg','Beef Bulgogi','Hungarian Sausage','Chicken Eggdrop','Criss Cut','Crispy Loops','Bufallo Skinny','Tteokbokki','Strawberry','Iced Coffee','Iced Matcha','Iced Choco','Orange Juice','Iced Tea','Sea Salt Cream'};
                items_sold = [app.EggCheddarSandwichCounter, app.EggSaladSandwichCounter,app.TunaMeltSandwichCounter,app.DoubleHamEggCheeseCounter,app.BaconEggandCheeseCounter,app.SpamandEggSandwichCounter,app.PremiumBeefBulgogiSandwichCounter,app.HungarianSausageEggDropCounter,app.ChickenEggdropSandwichCounter,app.CrissCutFriesCounter,app.CrispyLoopsFriesCounter,app.BuffaloSkinnyFriesCounter,app.TteokbokkiwithEggCheeseCounter,app.StrawberryMilkLatteCounter,app.IcedCoffeeCounter,app.IcedMatchaCounter,app.IcedChocolateCounter,app.OrangeJuiceCounter,app.IcedTeaCounter,app.SeaSaltCreamCounter];
                net_sales = [app.EggCheddarSandwichCounter * 99, app.EggSaladSandwichCounter * 129,app.TunaMeltSandwichCounter * 159,app.DoubleHamEggCheeseCounter * 149,app.BaconEggandCheeseCounter * 159,app.SpamandEggSandwichCounter * 159,app.PremiumBeefBulgogiSandwichCounter * 169,app.HungarianSausageEggDropCounter * 169,app.ChickenEggdropSandwichCounter * 169,app.CrissCutFriesCounter * 109,app.CrispyLoopsFriesCounter * 109,app.BuffaloSkinnyFriesCounter * 109,app.TteokbokkiwithEggCheeseCounter * 120,app.StrawberryMilkLatteCounter * 90,app.IcedCoffeeCounter * 70,app.IcedMatchaCounter * 70,app.IcedChocolateCounter * 70,app.OrangeJuiceCounter * 40,app.IcedTeaCounter * 40,app.SeaSaltCreamCounter * 20];
                gross_profit = net_sales - [app.EggCheddarSandwichCounter * 53.53, app.EggSaladSandwichCounter * 69.75,app.TunaMeltSandwichCounter * 80.39,app.DoubleHamEggCheeseCounter * 69.13,app.BaconEggandCheeseCounter * 66.21,app.SpamandEggSandwichCounter * 81.75,app.PremiumBeefBulgogiSandwichCounter * 88.05,app.HungarianSausageEggDropCounter * 87.63,app.ChickenEggdropSandwichCounter * 83.08,app.CrissCutFriesCounter * 62.80,app.CrispyLoopsFriesCounter * 62.80,app.BuffaloSkinnyFriesCounter * 62.80,app.TteokbokkiwithEggCheeseCounter * 53.66,app.StrawberryMilkLatteCounter * 40.54,app.IcedCoffeeCounter * 26.25,app.IcedMatchaCounter * 26.4,app.IcedChocolateCounter * 21.85,app.OrangeJuiceCounter * 16.66,app.IcedTeaCounter * 16.66,app.SeaSaltCreamCounter * 17.38];
                
                % Find rows with non-zero values in items_sold or net_sales
                valid_rows = any([items_sold; net_sales], 1);
                
                % Create table with only valid rows
                T = table(date(valid_rows),time(valid_rows), item_name(valid_rows)', items_sold(valid_rows)', net_sales(valid_rows)', gross_profit(valid_rows)');
                
                % Get the root directory of the app
                directory = fileparts(mfilename('fullpath'));

                % Define the assets folder path and file name
                assets_path = fullfile(directory, 'assets', 'sales.csv');
                
                % Write the table to the CSV file
                if exist(assets_path, 'file')
                    % Append the new data to the existing CSV file
                    writetable(T, assets_path, 'WriteMode', 'Append');
                else
                    % Save the table as a CSV file
                    writetable(T, assets_path);
                end


            end
        end

        % Value changed function: DatePicker
        function DatePickerValueChanged(app, event)
          
        end

        % Value changed function: GraphDropDown
        function GraphDropDownValueChanged(app, event)
          
            % Read the data from the CSV file
            % Get the root directory of the app
            directory = fileparts(mfilename('fullpath'));

            % Define the assets folder path and file name
            assets_path = fullfile(directory, 'assets', 'sales.csv');
            
            sales_data = readtable(assets_path);
        
            % Filter the data for the selected date
            selected_date = app.DatePicker.Value;
            selected_date_str = datestr(selected_date, 'dd-mmm-yyyy');
            selected_date_dt = datetime(selected_date_str, 'InputFormat', 'dd-MMM-yyyy');
            sales_data = sales_data(sales_data.Var1 == selected_date_dt, :);
        
            % Extract the hours from the datetime array
            hours_array = hour(sales_data.Var1);
        
            % Clear any existing plots on the UIAxes
            cla(app.UIAxes);
            cla(app.UIAxes2);
           
            % Get the selected value from the dropdown
            selected_option = app.GraphDropDown.Value;
        
            % Plot the data based on the selected option
            switch selected_option
                case 'Select'
                    cla(app.UIAxes);
                    cla(app.UIAxes2);
                    app.UITable.Visible = 'off';
                    app.UIAxes.Visible = 'off';
                    app.UIAxes2.Visible = 'off';
                case 'Dot Graph'
                    app.UITable.Visible = 'off';
                    app.UIAxes.Visible = 'on';
                    app.UIAxes2.Visible = 'off';
                    
                    for i = 1:size(sales_data, 1)
                        x = sales_data.Var2(i) + hours_array(i);
                        y = sales_data.Var5(i);
                        plot(app.UIAxes, x, y, '-o', 'DisplayName', sales_data.Var3{i});
                        text(app.UIAxes, x, y+5, sales_data.Var3{i}, 'HorizontalAlignment', 'center');
                        hold(app.UIAxes, 'on');
                    end
                    
                    % Set the legend to the item names
                    legend(app.UIAxes, 'show');
                    
                    % Update the UIAxes labels
                    title(app.UIAxes, 'Sales Data for Selected Date');
                    xlabel(app.UIAxes, 'Time');
                    ylabel(app.UIAxes, 'Items Sold');

                
                case 'Pie Graph'
                    % Calculate the percentage of each item's sales
                    app.UITable.Visible = 'off';
                    app.UIAxes2.Visible = 'on';
                    app.UIAxes.Visible = 'off';
                    percentages = 100 * sales_data.Var5 / sum(sales_data.Var5);
                    
                    % Create the pie chart
                    h = pie(app.UIAxes2, sales_data.Var5, sales_data.Var3);
                    
                    % Set the legend to the item names
                    legend(app.UIAxes2, sales_data.Var3);
                    
                    % Update the UIAxes title
                    title(app.UIAxes2, 'Sales Data for Selected Date');
                    
                    % Add percentages to pie chart labels
                    textObjs = findobj(h, 'Type', 'text');
                    for i = 1:length(textObjs)
                        set(textObjs(i), 'String', sprintf('%s\n%.1f%%', sales_data.Var3{i}, percentages(i)));
                    end

                case 'Gross Profit'
                    app.UITable.Visible = 'on';
                    app.UIAxes.Visible = 'off';
                    app.UIAxes2.Visible = 'off';
                    % Extract the required columns from the sales data
                    item_name = sales_data.Var3;
                    item_sold = sales_data.Var4;
                    net_sales = sales_data.Var5;
                    gross_profit = sales_data.Var6;
                    
                    % Combine the columns into a table
                    sales_table = table(item_name, item_sold, net_sales, gross_profit);
                    
                    % Sort the table by gross profit in descending order
                    sales_table = sortrows(sales_table, 'gross_profit', 'descend');
                    
                    % Display the table in the UI
                    app.UITable.Data = sales_table;
        
            end


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.MenuTab = uitab(app.TabGroup);
            app.MenuTab.Title = 'Menu';
            app.EggCheddarSandwichButton = uibutton(app.MenuTab, 'push');
            app.EggCheddarSandwichButton.ButtonPushedFcn = createCallbackFcn(app, @EggCheddarSandwichButtonPushed, true);
            app.EggCheddarSandwichButton.Text = {'Egg Cheddar '; 'Sandwich'};
            app.EggSaladSandwichButton = uibutton(app.MenuTab, 'push');
            app.EggSaladSandwichButton.ButtonPushedFcn = createCallbackFcn(app, @EggSaladSandwichButtonPushed, true);
            app.EggSaladSandwichButton.Text = {'Egg Salad'; 'Sandwich'};
            app.TunaMeltSandwichButton = uibutton(app.MenuTab, 'push');
            app.TunaMeltSandwichButton.ButtonPushedFcn = createCallbackFcn(app, @TunaMeltSandwichButtonPushed, true);
            app.TunaMeltSandwichButton.Text = {'Tuna Melt'; 'Sandwich'};
            app.DoubleHamEggCheeseButton = uibutton(app.MenuTab, 'push');
            app.DoubleHamEggCheeseButton.ButtonPushedFcn = createCallbackFcn(app, @DoubleHamEggCheeseButtonPushed, true);
            app.DoubleHamEggCheeseButton.Text = {'Double Ham'; 'Egg & Cheese'};
            app.BaconEggandCheeseButton_3 = uibutton(app.MenuTab, 'push');
            app.BaconEggandCheeseButton_3.ButtonPushedFcn = createCallbackFcn(app, @BaconEggandCheeseButton_3Pushed, true);
            app.BaconEggandCheeseButton_3.Text = {'Bacon Egg'; 'and Cheese'};
            app.SpamandEggSandwichButton = uibutton(app.MenuTab, 'push');
            app.SpamandEggSandwichButton.ButtonPushedFcn = createCallbackFcn(app, @SpamandEggSandwichButtonPushed, true);
            app.SpamandEggSandwichButton.Text = {'Spam and Egg'; 'Sandwich'};
            app.PremiumBeefBulgogiSandwichButton_4 = uibutton(app.MenuTab, 'push');
            app.PremiumBeefBulgogiSandwichButton_4.ButtonPushedFcn = createCallbackFcn(app, @PremiumBeefBulgogiSandwichButton_4Pushed, true);
            app.PremiumBeefBulgogiSandwichButton_4.Text = {'Premium Beef'; 'Bulgogi Sandwich'};
            app.HungarianSausageEggDropButton_2 = uibutton(app.MenuTab, 'push');
            app.HungarianSausageEggDropButton_2.ButtonPushedFcn = createCallbackFcn(app, @HungarianSausageEggDropButton_2Pushed, true);
            app.HungarianSausageEggDropButton_2.Text = {'Hungarian'; 'Sausage'; 'Egg Drop'};
            app.ChickenEggdropSandwichButton = uibutton(app.MenuTab, 'push');
            app.ChickenEggdropSandwichButton.ButtonPushedFcn = createCallbackFcn(app, @ChickenEggdropSandwichButtonPushed, true);
            app.ChickenEggdropSandwichButton.Text = {'Chicken Eggdrop'; 'Sandwich'};
            app.CrissCutFriesButton = uibutton(app.MenuTab, 'push');
            app.CrissCutFriesButton.ButtonPushedFcn = createCallbackFcn(app, @CrissCutFriesButtonPushed, true);
            app.CrissCutFriesButton.Text = 'Criss Cut Fries';
            app.CrispyLoopsFriesButton = uibutton(app.MenuTab, 'push');
            app.CrispyLoopsFriesButton.ButtonPushedFcn = createCallbackFcn(app, @CrispyLoopsFriesButtonPushed, true);
            app.CrispyLoopsFriesButton.Text = {'Crispy Loops'; 'Fries'};
            app.BuffaloSkinnyFriesButton = uibutton(app.MenuTab, 'push');
            app.BuffaloSkinnyFriesButton.ButtonPushedFcn = createCallbackFcn(app, @BuffaloSkinnyFriesButtonPushed, true);
            app.BuffaloSkinnyFriesButton.Text = {'Buffalo Skinny'; 'Fries'};
            app.TteokbokkiwithEggCheeseButton = uibutton(app.MenuTab, 'push');
            app.TteokbokkiwithEggCheeseButton.ButtonPushedFcn = createCallbackFcn(app, @TteokbokkiwithEggCheeseButtonPushed, true);
            app.TteokbokkiwithEggCheeseButton.Text = {'Tteokbokki with'; 'Egg & Cheese'};
            app.StrawberryMilkLatteButton = uibutton(app.MenuTab, 'push');
            app.StrawberryMilkLatteButton.ButtonPushedFcn = createCallbackFcn(app, @StrawberryMilkLatteButtonPushed, true);
            app.StrawberryMilkLatteButton.Text = {'Strawberry'; 'Milk Latte'};
            app.IcedCoffeeButton = uibutton(app.MenuTab, 'push');
            app.IcedCoffeeButton.ButtonPushedFcn = createCallbackFcn(app, @IcedCoffeeButtonPushed, true);
            app.IcedCoffeeButton.Text = 'Iced Coffee';
            app.IcedMatchaButton = uibutton(app.MenuTab, 'push');
            app.IcedMatchaButton.ButtonPushedFcn = createCallbackFcn(app, @IcedMatchaButtonPushed, true);
            app.IcedMatchaButton.Text = 'Iced Matcha';
            app.IcedChocolateButton = uibutton(app.MenuTab, 'push');
            app.IcedChocolateButton.ButtonPushedFcn = createCallbackFcn(app, @IcedChocolateButtonPushed, true);
            app.IcedChocolateButton.Text = 'Iced Chocolate';
            app.OrangeJuiceButton = uibutton(app.MenuTab, 'push');
            app.OrangeJuiceButton.ButtonPushedFcn = createCallbackFcn(app, @OrangeJuiceButtonPushed, true);
            app.OrangeJuiceButton.Text = 'Orange Juice';
            app.IcedTeaButton = uibutton(app.MenuTab, 'push');
            app.IcedTeaButton.ButtonPushedFcn = createCallbackFcn(app, @IcedTeaButtonPushed, true);
            app.IcedTeaButton.Text = 'Iced Tea';
            app.SeaSaltCreamButton = uibutton(app.MenuTab, 'push');
            app.SeaSaltCreamButton.ButtonPushedFcn = createCallbackFcn(app, @SeaSaltCreamButtonPushed, true);
            app.SeaSaltCreamButton.Text = 'Sea Salt Cream';
            app.VoidButton = uibutton(app.MenuTab, 'push');
            app.VoidButton.ButtonPushedFcn = createCallbackFcn(app, @VoidButtonPushed, true);
            app.VoidButton.Text = 'Void';
            app.CheckOutButton = uibutton(app.MenuTab, 'push');
            app.CheckOutButton.ButtonPushedFcn = createCallbackFcn(app, @CheckOutButtonPushed, true);
            app.CheckOutButton.Text = 'Check Out';
            app.TextAreaLabel = uilabel(app.MenuTab);
            app.TextAreaLabel.Text = 'Text Area';
            app.TextArea = uitextarea(app.MenuTab);
            app.CheckOutPanel = uipanel(app.MenuTab);
            app.CheckOutPanel.Title = 'Check Out';
            app.CheckOutPanel.Visible = 'off';
            app.XButton = uibutton(app.CheckOutPanel, 'push');
            app.XButton.ButtonPushedFcn = createCallbackFcn(app, @XButtonPushed, true);
            app.XButton.Text = 'X';
            app.TenderedAmountEditFieldLabel = uilabel(app.CheckOutPanel);
            app.TenderedAmountEditFieldLabel.Text = 'Tendered Amount :';
            app.TenderedAmountEditField = uieditfield(app.CheckOutPanel, 'text');
            app.ToPayPhpLabel = uilabel(app.CheckOutPanel);
            app.ToPayPhpLabel.Text = 'To Pay :           Php';
            app.ToPayPhpEditField = uieditfield(app.CheckOutPanel, 'text');
            app.ChangePhpLabel = uilabel(app.CheckOutPanel);
            app.ChangePhpLabel.Text = 'Change :          Php';
            app.ChangePhpEditField = uieditfield(app.CheckOutPanel, 'text');
            app.ProceedButton = uibutton(app.CheckOutPanel, 'push');
            app.ProceedButton.ButtonPushedFcn = createCallbackFcn(app, @ProceedButtonPushed, true);
            app.ProceedButton.Text = 'Proceed';
            app.TextArea2 = uitextarea(app.CheckOutPanel);
            app.TextArea2.Visible = 'off';
            app.TextArea2.Value = {'Thank You For Your Purchase!'};
            app.AnalyticsTab = uitab(app.TabGroup);
            app.AnalyticsTab.Tooltip = {''};
            app.AnalyticsTab.Title = 'Analytics';
            app.UIAxes = uiaxes(app.AnalyticsTab);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.Visible = 'off';
            app.UIAxes2 = uiaxes(app.AnalyticsTab);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes2.Visible = 'off';
            app.DatePickerLabel = uilabel(app.AnalyticsTab);
            app.DatePickerLabel.Text = 'Date Picker';
            app.DatePicker = uidatepicker(app.AnalyticsTab);
            app.DatePicker.ValueChangedFcn = createCallbackFcn(app, @DatePickerValueChanged, true);
            app.GraphDropDownLabel = uilabel(app.AnalyticsTab);
            app.GraphDropDownLabel.Text = 'Graph';
            app.GraphDropDown = uidropdown(app.AnalyticsTab);
            app.GraphDropDown.Items = {'Select', 'Dot Graph', 'Pie Graph', 'Gross Profit'};
            app.GraphDropDown.ValueChangedFcn = createCallbackFcn(app, @GraphDropDownValueChanged, true);
            app.GraphDropDown.Value = 'Select';
            app.UITable = uitable(app.AnalyticsTab);
            app.UITable.ColumnName = {'Item Name'; 'Item Sold'; 'Net Sales'; 'Gross Profit'};
            app.UITable.RowName = {};
            app.UITable.Visible = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GroupProject

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end